classdef Wtapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SetupTab                      matlab.ui.container.Tab
        ScanRateEditFieldLabel        matlab.ui.control.Label
        ScanRateEditField             matlab.ui.control.NumericEditField
        ScanTimeEditFieldLabel        matlab.ui.control.Label
        ScanTimeEditField             matlab.ui.control.EditField
        LabJackLabel                  matlab.ui.control.Label
        MensorPressureScannerLabel    matlab.ui.control.Label
        InitumPitotScannerLabel       matlab.ui.control.Label
        VelmexTraverseLabel           matlab.ui.control.Label
        secondsLabel                  matlab.ui.control.Label
        HzLabel                       matlab.ui.control.Label
        LabjackDAQLabel               matlab.ui.control.Label
        labjacklamp                   matlab.ui.control.Lamp
        initiumlamp                   matlab.ui.control.Lamp
        velmexlamp                    matlab.ui.control.Lamp
        mensorlamp                    matlab.ui.control.Lamp
        StatusLabel                   matlab.ui.control.Label
        ConnectMensorButton           matlab.ui.control.Button
        ConnectVelmexButton           matlab.ui.control.Button
        ConnectInitiumButton          matlab.ui.control.Button
        ConnectLabjackButton          matlab.ui.control.Button
        weatherlamp                   matlab.ui.control.Lamp
        WeatherSensorsLabel           matlab.ui.control.Label
        ConnectWeathermanButton       matlab.ui.control.Button
        ClearSensorsButton            matlab.ui.control.Button
        CustomLabjackscanButton       matlab.ui.control.StateButton
        Lamp                          matlab.ui.control.Lamp
        ConnectAllButton              matlab.ui.control.Button
        LockSettingsButton            matlab.ui.control.StateButton
        CalibrateTab                  matlab.ui.container.Tab
        TareTab                       matlab.ui.container.Tab
        TareNumberEditField_2Label    matlab.ui.control.Label
        TareNumberEditField_2         matlab.ui.control.NumericEditField
        TareFileNameEditFieldLabel    matlab.ui.control.Label
        TareFileNameEditField         matlab.ui.control.EditField
        TakeTareButton                matlab.ui.control.Button
        UpdateTareNumberButton        matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        AdjustTareRecordingOptionsPanel  matlab.ui.container.Panel
        TareScanRateEditFieldLabel    matlab.ui.control.Label
        TareScanRateEditField         matlab.ui.control.NumericEditField
        TareScanTimeEditFieldLabel    matlab.ui.control.Label
        TareScanTimeEditField         matlab.ui.control.NumericEditField
        UseSpecialTareSettingsButton  matlab.ui.control.StateButton
        PerformancePolarTab           matlab.ui.container.Tab
        UpdateButton                  matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        CalibrationNumberEditFieldLabel  matlab.ui.control.Label
        CalibrationNumberEditField    matlab.ui.control.EditField
        PWMstepEditFieldLabel         matlab.ui.control.Label
        PWMstepUpdate                 matlab.ui.control.NumericEditField
        PWMmaxEditFieldLabel          matlab.ui.control.Label
        PWMmaxUpdate                  matlab.ui.control.NumericEditField
        ReadAoAButton                 matlab.ui.control.Button
        TareNumberEditFieldLabel      matlab.ui.control.Label
        TareNumberEditField           matlab.ui.control.EditField
        RunNumberEditFieldLabel       matlab.ui.control.Label
        RunNumber                     matlab.ui.control.EditField
        NewLabel                      matlab.ui.control.Label
        CurrentLabel                  matlab.ui.control.Label
        PWMstepCurrent                matlab.ui.control.NumericEditField
        PWMmaxCurrent                 matlab.ui.control.NumericEditField
        AoAcurrent                    matlab.ui.control.NumericEditField
        AngleofAttackLabel            matlab.ui.control.Label
        RunFileRootEditFieldLabel     matlab.ui.control.Label
        RunFileRootEditField          matlab.ui.control.EditField
        NewRunButton                  matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        TareButton                    matlab.ui.control.Button
        TabGroup2                     matlab.ui.container.TabGroup
        PolarTab                      matlab.ui.container.Tab
        polarplot                     matlab.ui.control.UIAxes
        ClalphaTab                    matlab.ui.container.Tab
        claplot                       matlab.ui.control.UIAxes
        CxalphaTab                    matlab.ui.container.Tab
        cxaplot                       matlab.ui.control.UIAxes
        CmalphaTab                    matlab.ui.container.Tab
        cmaplot                       matlab.ui.control.UIAxes
        LockButton                    matlab.ui.control.StateButton
        RunNotesTextAreaLabel         matlab.ui.control.Label
        RunNotesTextArea              matlab.ui.control.TextArea
        FlapAngleEditFieldLabel       matlab.ui.control.Label
        FlapAngleEditField            matlab.ui.control.NumericEditField
        FlapAngleEditField_2          matlab.ui.control.NumericEditField
        WakeStudyTab                  matlab.ui.container.Tab
        TraverseTab                   matlab.ui.container.Tab
        GridSetupPanel                matlab.ui.container.Panel
        YmaxEditFieldLabel            matlab.ui.control.Label
        YmaxEditField                 matlab.ui.control.NumericEditField
        YStepLabel                    matlab.ui.control.Label
        YStepEditField                matlab.ui.control.NumericEditField
        YminEditFieldLabel            matlab.ui.control.Label
        YminEditField                 matlab.ui.control.NumericEditField
        ZminEditFieldLabel            matlab.ui.control.Label
        ZminEditField                 matlab.ui.control.NumericEditField
        ZmaxEditFieldLabel            matlab.ui.control.Label
        ZmaxEditField                 matlab.ui.control.NumericEditField
        ZStepEditFieldLabel           matlab.ui.control.Label
        ZStepEditField                matlab.ui.control.NumericEditField
        LockGridButton                matlab.ui.control.StateButton
        UpdateButton_2                matlab.ui.control.StateButton
        StepLeftButton                matlab.ui.control.Button
        StepUpButton                  matlab.ui.control.Button
        StepDownButton                matlab.ui.control.Button
        StepRightButton               matlab.ui.control.Button
        StepSizeEditFieldLabel        matlab.ui.control.Label
        StepSizeEditField             matlab.ui.control.NumericEditField
        SetOriginButton_2             matlab.ui.control.Button
        GoHomeButton_2                matlab.ui.control.Button
        ToggleButtonGroup             matlab.ui.container.ButtonGroup
        GoToButton                    matlab.ui.control.ToggleButton
        GridSetupButton               matlab.ui.control.ToggleButton
        ManualControlButton           matlab.ui.control.ToggleButton
        GoToPanel                     matlab.ui.container.Panel
        YpositioninEditFieldLabel     matlab.ui.control.Label
        yposmove                      matlab.ui.control.NumericEditField
        ZpositioninEditFieldLabel     matlab.ui.control.Label
        zposmove                      matlab.ui.control.NumericEditField
        SetOriginButton               matlab.ui.control.Button
        GoHomeButton                  matlab.ui.control.Button
        MoveButton                    matlab.ui.control.Button
        NegativeZButton               matlab.ui.control.StateButton
        NegativeYButton               matlab.ui.control.StateButton
        YpositionEditFieldLabel       matlab.ui.control.Label
        YpositionEditField            matlab.ui.control.NumericEditField
        ZpositionEditFieldLabel       matlab.ui.control.Label
        ZpositionEditField            matlab.ui.control.NumericEditField
        CurrentPositionLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        %% TO DO
        % -list of properties to figure out that can be used between
        % functions
        
        
        % sensors
        Initium 
        Velmex
        WeatherMan
        Labjack
        Mensor
        
        % passed between functions a lot
        
        
        % other


    end
    
    methods (Access = private)
        
        function results = addpaths(app)
            % adds paths needed to access all parts of the WT run program.
            % run inside of functions when they need to call a file from
            % outside enclosing folder
            
            toolbox_path = "C:\Users\longt\Documents\mit-git\toolbox_update\toolbox\aawind\aawind\";
            tbt_path     = "C:\Users\longt\Documents\mit-git\3by2\WTrun\";
            addpath(toolbox_path,tbt_path);
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            toolbox_path = "C:\Users\longt\Documents\mit-git\toolbox_update\toolbox\aawind\aawind\";
            tbt_path     = "C:\Users\longt\Documents\mit-git\3by2\WTrun\";
            addpath(toolbox_path,tbt_path);
        end

        % Button pushed function: ReadAoAButton
        function ReadAoAButtonPushed(app, event)
            % update AoA value
            AoA = readAttackAngle(labjack);
            %%% NEED TO WRITE THIS FUNCTION
            
            % printout of AoA value
            set(app.AoAcurrent,'Value',AoA)
            
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            
            % get file numbers for the file root to display
            try
                calnumber  = str2num(app.CalibrationNumberEditField.Value);
                tarenumber = str2num(app.TareNumberEditField.Value);
                runNumber  = str2num(app.RunNumber.Value);
            
            catch
                warning('Invalid input number for run file')
            end
            filename   = sprintf('run%02.0f_%03.0f_%03.0f_XX.mat', calnumber,tarenumber,runNumber);
            
            % update file name
            app.RunFileRootEditField.set('Value',filename)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % gather numbers for performance run
            
                % run Number
                % cal Number
                % tare Number
                
            try
                % set up run numbers
                calnumber  = str2double(app.CalibrationNumberEditField.Value);
                tarenumber = str2double(app.TareNumberEditField.Value);
                runnumber  = str2double(app.RunNumber.Value);
                
                % pwm vec
                PWMmax = app.PWMmaxCurrent.Value;
                PWMstep = app.PWMstepCurrent.Value;
                PWMvec = 1000:PWMstep:PWMmax;
                
                % comments and flap angle
                runComments = app.RunNotesTextArea.Value;
                flapangle = str2double(app.FlapAngleEditField.Value);


            catch
                warning('Issues setting up collectData inputs, check inputs')
            end
                
           % run data collection function
           try
               [runfiles,corrected_tables,uncorrected_tables] = collectData( ...
                                app.Labjack,...
                                app.Initium,...
                                app.WeatherMan,...
                                app.Mensor,...
                                calnumber,...
                                tarenumber,...
                                runnumber,...
                                PWMvec,...
                                runComments,...
                                flapangle);
           catch e
               warning('data collection operation failed')
           end
           
                        
           % update visuals
           
           % set up vectors to plot
           Ntables = length(corrected_tables);
           clrun = cell(Ntables,1);
           cxrun = cell(Ntables,1);
           cmrun = cell(Ntables,1);
           alfarun = cell(Ntables,1);
           
           for N = 1:length(Ntables)
               tblN  = corrected_tables{N};
               cl(N) = tblN.cl_average;
               cx(N) = tblN.cx_average;
               cm(N) = tblN.cm_average;
               alfa(N) = mean(tblN.alfa);
               dcj(N)  = mean(tblN.dCJ);
           end
           
           
           % plot outputs
           
           %polar
           hold(app.polarplot,'on') % retain previous entries
           scatter(app.polarplot,cl,cx,dcj,'filled') % plot new entries
           
           
           %cl-alpha
           hold(app.claplot,'on')
           scatter(app.claplot,alfa,cl,dcj,'filled')
           
           
           %cx-alpha
           hold(app.cxaplot,'on')
           scatter(app.cxaplot,alfa,cx,dcj,'filled')
           
           
           %cm-alpha
           hold(app.cmaplot,'on')
           %scatter(app.cmaplot,alfa,)
        
        end

        % Value changed function: LockButton
        function LockButtonValueChanged(app, event)
            value = app.LockButton.Value;
            
            % set
            if value ==1
                set(app.UpdateButton,'Enable','off');
                set(app.ResetButton,'Enable','off');
                set(app.ReadAoAButton,'Enable','off');
                set(app.TareButton,'Enable','off');
                set(app.NewRunButton,'Enable','off');
                
            else
                set(app.UpdateButton,'Enable','on');
                set(app.ResetButton,'Enable','on');
                set(app.ReadAoAButton,'Enable','on');
                set(app.TareButton,'Enable','on');
                set(app.NewRunButton,'Enable','on');
            end
             
        end

        % Selection changed function: ToggleButtonGroup
        function ToggleButtonGroupSelectionChanged(app, event)
            
            
            selectedButton = app.ToggleButtonGroup.SelectedObject;
            
            switch selectedButton
                % 
                case app.GoToButton 
                    %disable all but GoTo tab   
                    
                    % GoTo
                    app.MoveButton.set('Enable','on');
                    app.SetOriginButton.set('Enable','on');
                    
                    % grid setup
                    app.LockGridButton.set('Enable','off');
                    app.UpdateButton_2.set('Enable','off');
                    
                    % manual control
                    
                    app.StepUpButton.set('Enable','off');
                    app.StepDownButton.set('Enable','off');
                    app.StepLeftButton.set('Enable','off');
                    app.StepRightButton.set('Enable','off');
                    app.SetOriginButton_2.set('Enable','off');
                    
                    
                case app.GridSetupButton           
                %disable all but GridSetup tab   
                    
                    % GoTo
                    app.MoveButton.set('Enable','off');
                    app.SetOriginButton.set('Enable','off');
                    
                    % grid setup
                    app.LockGridButton.set('Enable','on');
                    app.UpdateButton_2.set('Enable','on');
                    
                    % manual control
                    
                    app.StepUpButton.set('Enable','off');
                    app.StepDownButton.set('Enable','off');
                    app.StepLeftButton.set('Enable','off');
                    app.StepRightButton.set('Enable','off');
                    app.SetOriginButton_2.set('Enable','off');
                    
                case app.ManualControlButton
                    %disable all but GoTo tab   
                    
                    % GoTo
                    app.MoveButton.set('Enable','off');
                    app.SetOriginButton.set('Enable','off');
                    
                    % grid setup
                    app.LockGridButton.set('Enable','off');
                    app.UpdateButton_2.set('Enable','off');
                    
                    % manual control
                    
                    app.StepUpButton.set('Enable','on');
                    app.StepDownButton.set('Enable','on');
                    app.StepLeftButton.set('Enable','on');
                    app.StepRightButton.set('Enable','on');
                    app.SetOriginButton_2.set('Enable','on');
                
                
            end
            
        end

        % Button pushed function: StepUpButton
        function StepUpButtonPushed(app, event)
            % move up number of inches in "stepsize" 
            
            stepsize = app.StepSizeEditField.Value;
            
            % multiply by -1 if negative z
            
            if app.NegativeZButton.Value == 1
                stepsize = -stepsize;
            end
            
            app.Velmex.relativeMove([0,0,stepsize]);
        end

        % Button pushed function: StepDownButton
        function StepDownButtonPushed(app, event)
        % move down number of inches in "stepsize" 
            
            stepsize = - app.StepSizeEditField.Value;
            
            % multiply by -1 if negative z
            
            if app.NegativeZButton.Value == 1
                stepsize = -stepsize;
            end
            
            app.Velmex.relativeMove([0,0,stepsize]);
        end

        % Button pushed function: StepRightButton
        function StepRightButtonPushed(app, event)
            % move right (positive) number of inches in "stepsize" 
            
            stepsize = app.StepSizeEditField.Value;
            
            % multiply by -1 if negative z
            
            if app.NegativeYButton.Value == 1
                stepsize = -stepsize;
            end
            
            app.Velmex.relativeMove([stepsize,0,0]);
        end

        % Button pushed function: StepLeftButton
        function StepLeftButtonPushed(app, event)
            % move right (positive) number of inches in "stepsize" 
            
            stepsize = -app.StepSizeEditField.Value;
            
            % multiply by -1 if negative z
            
            if app.NegativeYButton.Value == 1
                stepsize = -stepsize;
            end
            
            app.Velmex.relativeMove([stepsize,0,0]);
        end

        % Button pushed function: ConnectMensorButton
        function ConnectMensorButtonPushed(app, event)
        % connect Mensor Scanner
            if app.mensorlamp.Color == [1,0,0]
                
                try
                    mensor  = aawind.Mensor('COM6');
                    app.mensorlamp.set('Color',[0,1,0]);
                    
                    % add as property
                    app.Mensor = mensor;
                    
                catch e
                    warning('a problem occured connecting to the mensor, check connections and COM ports and try again')
                end
                
            else
                warning('sensor already connected, reset to connect again')
            end
        end

        % Button pushed function: ConnectWeathermanButton
        function ConnectWeathermanButtonPushed(app, event)
         % connect Weather Scanner
         
            if app.weatherlamp.Color(1) == 1
                
                try
                    
                weather  = aawind.Weather('COM3');
                app.weatherlamp.set('Color',[0,1,0]);
                
                % add as property
                app.WeatherMan = weather;
                catch ME
                    warning("an error occured connecting to the weather channel, check connection and try again")
                end

                
            else
                warning('sensor already connected, reset to connect again')
            end
        end

        % Button pushed function: ConnectLabjackButton
        function ConnectLabjackButtonPushed(app, event)
            % connect Mensor Scanner
            
            % pull scanrate, runtime from user inputs
            if double(get(app.CustomLabjackscanButton,'Value')) == 1.0
                
                scanrate = double(app.ScanRateEditField.get('Value')) ;% Hz
                scantime = double(app.ScanTimeEditField.get('Value'));
                
            else
                scanrate = 100; %hz
                scantime = 3; % seconds
            end

            
            % set to default if not checked
            
            %
            if app.labjacklamp.Color(1) == 1    
                
                
                try
                    % set up labjack and set run properties
                    LJbox1 = LJbox;
                    LJbox1.setup();
                    LJbox1.connect()
                    LJbox1.scanRate = scanrate;
                    LJbox1.scanTime = scantime;
                    
                    % add as property
                    app.Labjack = LJbox1;
                    
                catch e
                    warning('an error occured connecting to the labjack, check connections and try again')
                end
                    
                
            else
                warning('sensor already connected, reset to connect again')
            end
        end

        % Button pushed function: ConnectInitiumButton
        function ConnectInitiumButtonPushed(app, event)
            % connect the Initium pressure sensor (big box), all
            % adjustments to the setup must be done in this code and not
            % via the interface for the moment
            
            % TO DO
            % - make setup programatic
            % -possibly add an initum tab/subscreen
            %
            if app.initiumlamp.Color(1) == 1
            
                try
                    
                    initium = aawind.Initium;
                    initium.configureScanners([32 32 0 0]); %
                    initium.configureTable(1,101:132);
                    initium.configureTable(2,201:232); % double check that this is correct syntax
                    initium.zeroCalibration;
                    
                    % add as property
                    app.Initium = initium;
                catch InitiumError
                    warning('something went wrong connecting to Initium, restart the box/matlab to try again')
                end

                
            else
                warning('To reset the Initium, turn off the box, restart matlab, then turn the box back on before reconnecting')
            end
            
            
        end

        % Button pushed function: ConnectVelmexButton
        function ConnectVelmexButtonPushed(app, event)
            % connect Velmex-based traverse system
            if app.velmexlamp.Color(1) == 1
                
                try
                    velmex  = aawind.Velmex('COM7');
                    velmex.setOrigin;
                    app.velmexlamp.set('Color',[0,1,0]);
                    
                    % add as property
                    app.Velmex = velmex;
                    
                catch e
                    warning('An error occured connecting to the traverse')
                    
                end


            else
                warning('sensor already connected, reset to connect again')
            end
        end

        % Button pushed function: ClearSensorsButton
        function ClearSensorsButtonPushed(app, event)
            % clears everything but the Initium because that requires a
            % restart
            
            try
                clear app.Mensor app.Labjack app.Velmex app.WeatherMan
                
                % set indicator lamps to go red
                app.mensorlamp.set('Color',[1,0,0]);
                app.velmexlamp.set('Color',[1,0,0]);
                app.weatherlamp.set('Color',[1,0,0]);
                app.labjacklamp.set('Color',[1,0,0]);
                
            catch e
                warning('an error occured while clearing the sensors, restart matlab and attempt a reconnect')
            end
        end

        % Button pushed function: ConnectAllButton
        function ConnectAllButtonPushed(app, event)
            % TO DO
            
            
        end

        % Button pushed function: NewRunButton
        function NewRunButtonPushed(app, event)
            % just incriment run number automatically
            
            app.RunNumber.Value = str2double(app.RunNumber.Value) + 1;
            
            
        end

        % Value changed function: LockSettingsButton
        function LockSettingsButtonValueChanged(app, event)
            value = app.LockSettingsButton.Value;
            
            if value == 1
                
                set(app.ConnectMensorButton,'Enable','off');
                set(app.ConnectAllButton,'Enable','off');
                set(app.ConnectInitiumButton,'Enable','off');
                set(app.ConnectVelmexButton,'Enable','off');
                set(app.ConnectLabjackButton,'Enable','off');
                set(app.ConnectWeathermanButton,'Enable','off');
                
                % set input tabs off
                set(app.ScanRateEditField,'Enable','off');
                set(app.ScanTimeEditField,'Enable','off');
                set(app.CustomLabjackscanButton,'Enable','off');
                
            else
                % connect buttons on
                set(app.ConnectMensorButton,'Enable','on');
                set(app.ConnectAllButton,'Enable','on');
                set(app.ConnectInitiumButton,'Enable','on');
                set(app.ConnectVelmexButton,'Enable','on');
                set(app.ConnectLabjackButton,'Enable','on');
                set(app.ConnectWeathermanButton,'Enable','on');
                
                % user inputs on
                set(app.ScanRateEditField,'Enable','on');
                set(app.ScanTimeEditField,'Enable','on');
                set(app.CustomLabjackscanButton,'Enable','off');
            end


        end

        % Button pushed function: TakeTareButton
        function TakeTareButtonPushed(app, event)
            
            % grab tare number, scantime & scanrate
            tarenum = str2double(app.TareNumberEditField_2.Value);
            
            if app.UseSpecialTareSettingsButton.Value == 1
                scanrate = str2double(app.TareScanRateEditField.Value);
                scantime = str2double(app.TareScanTimeEditField.Value);
                
            else
                scantime = app.Labjack.scanTime;
                scanrate = app.Labjack.scanRate;
            end
            
            % run tare using existing function
            tare(app.Initium,app.Labjack,tarenum,scantime,scanrate)
            
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            % get positions from user
            ypos = app.yposmove.Value;
            zpos = app.zposmove.Value;
            
            % check if negative Y,Z apply
            
            if get(app.NegativeYButton,'Value') ==1
                ypos = - ypos;
            end
            
            if get(app.NegativeZButton,'Value') ==1
                zpos = - zpos;
            end
            
            
            % move to designated position
            
            app.Velmex.absoluteMove([ypos,0,zpos]);
        end

        % Value changed function: CustomLabjackscanButton
        function CustomLabjackscanButtonValueChanged(app, event)
            value = app.CustomLabjackscanButton.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.ScanRateEditFieldLabel = uilabel(app.SetupTab);
            app.ScanRateEditFieldLabel.Text = 'Scan Rate';
            app.ScanRateEditField = uieditfield(app.SetupTab, 'numeric');
            app.ScanRateEditField.Value = 100;
            app.ScanTimeEditFieldLabel = uilabel(app.SetupTab);
            app.ScanTimeEditFieldLabel.Text = 'Scan Time';
            app.ScanTimeEditField = uieditfield(app.SetupTab, 'text');
            app.ScanTimeEditField.Value = '3';
            app.LabJackLabel = uilabel(app.SetupTab);
            app.LabJackLabel.Text = 'LabJack';
            app.MensorPressureScannerLabel = uilabel(app.SetupTab);
            app.MensorPressureScannerLabel.Text = 'Mensor Pressure Scanner';
            app.InitumPitotScannerLabel = uilabel(app.SetupTab);
            app.InitumPitotScannerLabel.Text = 'Initum Pitot Scanner';
            app.VelmexTraverseLabel = uilabel(app.SetupTab);
            app.VelmexTraverseLabel.Text = 'Velmex Traverse';
            app.secondsLabel = uilabel(app.SetupTab);
            app.secondsLabel.Text = 'seconds';
            app.HzLabel = uilabel(app.SetupTab);
            app.HzLabel.Text = 'Hz';
            app.LabjackDAQLabel = uilabel(app.SetupTab);
            app.LabjackDAQLabel.Text = 'Labjack DAQ';
            app.labjacklamp = uilamp(app.SetupTab);
            app.initiumlamp = uilamp(app.SetupTab);
            app.velmexlamp = uilamp(app.SetupTab);
            app.mensorlamp = uilamp(app.SetupTab);
            app.StatusLabel = uilabel(app.SetupTab);
            app.StatusLabel.Text = 'Status';
            app.ConnectMensorButton = uibutton(app.SetupTab, 'push');
            app.ConnectMensorButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectMensorButtonPushed, true);
            app.ConnectMensorButton.Text = 'Connect Mensor';
            app.ConnectVelmexButton = uibutton(app.SetupTab, 'push');
            app.ConnectVelmexButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectVelmexButtonPushed, true);
            app.ConnectVelmexButton.Text = 'Connect Velmex';
            app.ConnectInitiumButton = uibutton(app.SetupTab, 'push');
            app.ConnectInitiumButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectInitiumButtonPushed, true);
            app.ConnectInitiumButton.Text = 'Connect Initium';
            app.ConnectLabjackButton = uibutton(app.SetupTab, 'push');
            app.ConnectLabjackButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectLabjackButtonPushed, true);
            app.ConnectLabjackButton.Text = 'Connect Labjack';
            app.weatherlamp = uilamp(app.SetupTab);
            app.WeatherSensorsLabel = uilabel(app.SetupTab);
            app.WeatherSensorsLabel.Text = 'Weather Sensors';
            app.ConnectWeathermanButton = uibutton(app.SetupTab, 'push');
            app.ConnectWeathermanButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectWeathermanButtonPushed, true);
            app.ConnectWeathermanButton.Text = 'Connect Weatherman';
            app.ClearSensorsButton = uibutton(app.SetupTab, 'push');
            app.ClearSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSensorsButtonPushed, true);
            app.ClearSensorsButton.Text = 'Clear Sensors';
            app.CustomLabjackscanButton = uibutton(app.SetupTab, 'state');
            app.CustomLabjackscanButton.ValueChangedFcn = createCallbackFcn(app, @CustomLabjackscanButtonValueChanged, true);
            app.CustomLabjackscanButton.Text = 'Custom Labjack scan';
            app.Lamp = uilamp(app.SetupTab);
            app.ConnectAllButton = uibutton(app.SetupTab, 'push');
            app.ConnectAllButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectAllButtonPushed, true);
            app.ConnectAllButton.Text = 'Connect All';
            app.LockSettingsButton = uibutton(app.SetupTab, 'state');
            app.LockSettingsButton.ValueChangedFcn = createCallbackFcn(app, @LockSettingsButtonValueChanged, true);
            app.LockSettingsButton.Text = 'Lock Settings';
            app.CalibrateTab = uitab(app.TabGroup);
            app.CalibrateTab.Title = 'Calibrate';
            app.TareTab = uitab(app.TabGroup);
            app.TareTab.Title = 'Tare';
            app.TareNumberEditField_2Label = uilabel(app.TareTab);
            app.TareNumberEditField_2Label.Text = 'Tare Number';
            app.TareNumberEditField_2 = uieditfield(app.TareTab, 'numeric');
            app.TareFileNameEditFieldLabel = uilabel(app.TareTab);
            app.TareFileNameEditFieldLabel.Text = 'Tare File Name';
            app.TareFileNameEditField = uieditfield(app.TareTab, 'text');
            app.TakeTareButton = uibutton(app.TareTab, 'push');
            app.TakeTareButton.ButtonPushedFcn = createCallbackFcn(app, @TakeTareButtonPushed, true);
            app.TakeTareButton.Text = 'Take Tare';
            app.UpdateTareNumberButton = uibutton(app.TareTab, 'push');
            app.UpdateTareNumberButton.Text = 'Update Tare Number';
            app.UIAxes = uiaxes(app.TareTab);
            title(app.UIAxes, 'Tare Info')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'Voltage')
            app.AdjustTareRecordingOptionsPanel = uipanel(app.TareTab);
            app.AdjustTareRecordingOptionsPanel.Title = 'Adjust Tare Recording Options';
            app.TareScanRateEditFieldLabel = uilabel(app.AdjustTareRecordingOptionsPanel);
            app.TareScanRateEditFieldLabel.Text = 'Tare Scan Rate';
            app.TareScanRateEditField = uieditfield(app.AdjustTareRecordingOptionsPanel, 'numeric');
            app.TareScanRateEditField.Value = 1000;
            app.TareScanTimeEditFieldLabel = uilabel(app.AdjustTareRecordingOptionsPanel);
            app.TareScanTimeEditFieldLabel.Text = 'Tare Scan Time';
            app.TareScanTimeEditField = uieditfield(app.AdjustTareRecordingOptionsPanel, 'numeric');
            app.TareScanTimeEditField.Value = 5;
            app.UseSpecialTareSettingsButton = uibutton(app.AdjustTareRecordingOptionsPanel, 'state');
            app.UseSpecialTareSettingsButton.Text = 'Use Special Tare Settings';
            app.PerformancePolarTab = uitab(app.TabGroup);
            app.PerformancePolarTab.Title = 'Performance Polar';
            app.UpdateButton = uibutton(app.PerformancePolarTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.RunButton = uibutton(app.PerformancePolarTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.CalibrationNumberEditFieldLabel = uilabel(app.PerformancePolarTab);
            app.CalibrationNumberEditFieldLabel.Text = 'Calibration Number';
            app.CalibrationNumberEditField = uieditfield(app.PerformancePolarTab, 'text');
            app.CalibrationNumberEditField.Value = '0';
            app.PWMstepEditFieldLabel = uilabel(app.PerformancePolarTab);
            app.PWMstepEditFieldLabel.Text = 'PWM  step';
            app.PWMstepUpdate = uieditfield(app.PerformancePolarTab, 'numeric');
            app.PWMstepUpdate.Value = 50;
            app.PWMmaxEditFieldLabel = uilabel(app.PerformancePolarTab);
            app.PWMmaxEditFieldLabel.Text = 'PWM  max';
            app.PWMmaxUpdate = uieditfield(app.PerformancePolarTab, 'numeric');
            app.PWMmaxUpdate.Value = 1500;
            app.ReadAoAButton = uibutton(app.PerformancePolarTab, 'push');
            app.ReadAoAButton.ButtonPushedFcn = createCallbackFcn(app, @ReadAoAButtonPushed, true);
            app.ReadAoAButton.Text = 'Read AoA';
            app.TareNumberEditFieldLabel = uilabel(app.PerformancePolarTab);
            app.TareNumberEditFieldLabel.Text = 'Tare Number';
            app.TareNumberEditField = uieditfield(app.PerformancePolarTab, 'text');
            app.TareNumberEditField.Value = '0';
            app.RunNumberEditFieldLabel = uilabel(app.PerformancePolarTab);
            app.RunNumberEditFieldLabel.Text = 'Run Number';
            app.RunNumber = uieditfield(app.PerformancePolarTab, 'text');
            app.RunNumber.Value = '0';
            app.NewLabel = uilabel(app.PerformancePolarTab);
            app.NewLabel.Text = 'New';
            app.CurrentLabel = uilabel(app.PerformancePolarTab);
            app.CurrentLabel.Text = 'Current';
            app.PWMstepCurrent = uieditfield(app.PerformancePolarTab, 'numeric');
            app.PWMstepCurrent.Value = 50;
            app.PWMmaxCurrent = uieditfield(app.PerformancePolarTab, 'numeric');
            app.PWMmaxCurrent.Value = 1500;
            app.AoAcurrent = uieditfield(app.PerformancePolarTab, 'numeric');
            app.AngleofAttackLabel = uilabel(app.PerformancePolarTab);
            app.AngleofAttackLabel.Text = 'Angle of Attack';
            app.RunFileRootEditFieldLabel = uilabel(app.PerformancePolarTab);
            app.RunFileRootEditFieldLabel.Text = 'Run File Root';
            app.RunFileRootEditField = uieditfield(app.PerformancePolarTab, 'text');
            app.NewRunButton = uibutton(app.PerformancePolarTab, 'push');
            app.NewRunButton.ButtonPushedFcn = createCallbackFcn(app, @NewRunButtonPushed, true);
            app.NewRunButton.Text = 'New Run';
            app.ResetButton = uibutton(app.PerformancePolarTab, 'push');
            app.ResetButton.Text = 'Reset';
            app.TareButton = uibutton(app.PerformancePolarTab, 'push');
            app.TareButton.Text = 'Tare';
            app.TabGroup2 = uitabgroup(app.PerformancePolarTab);
            app.PolarTab = uitab(app.TabGroup2);
            app.PolarTab.Title = 'Polar';
            app.polarplot = uiaxes(app.PolarTab);
            title(app.polarplot, 'Polar')
            xlabel(app.polarplot, 'Cx')
            ylabel(app.polarplot, 'Cl')
            app.ClalphaTab = uitab(app.TabGroup2);
            app.ClalphaTab.Title = 'Cl-alpha';
            app.claplot = uiaxes(app.ClalphaTab);
            title(app.claplot, 'Cl-alpha')
            xlabel(app.claplot, 'alpha (degrees)')
            ylabel(app.claplot, 'Cl')
            app.CxalphaTab = uitab(app.TabGroup2);
            app.CxalphaTab.Title = 'Cx-alpha';
            app.cxaplot = uiaxes(app.CxalphaTab);
            title(app.cxaplot, 'Cx-alpha')
            xlabel(app.cxaplot, 'alpha (degrees)')
            ylabel(app.cxaplot, 'Cx')
            app.CmalphaTab = uitab(app.TabGroup2);
            app.CmalphaTab.Title = 'Cm-alpha';
            app.cmaplot = uiaxes(app.CmalphaTab);
            title(app.cmaplot, 'Cm-alpha')
            xlabel(app.cmaplot, 'alpha (degrees)')
            ylabel(app.cmaplot, 'Cm')
            app.LockButton = uibutton(app.PerformancePolarTab, 'state');
            app.LockButton.ValueChangedFcn = createCallbackFcn(app, @LockButtonValueChanged, true);
            app.LockButton.Text = 'Lock';
            app.RunNotesTextAreaLabel = uilabel(app.PerformancePolarTab);
            app.RunNotesTextAreaLabel.Text = 'Run Notes';
            app.RunNotesTextArea = uitextarea(app.PerformancePolarTab);
            app.FlapAngleEditFieldLabel = uilabel(app.PerformancePolarTab);
            app.FlapAngleEditFieldLabel.Text = 'Flap Angle';
            app.FlapAngleEditField = uieditfield(app.PerformancePolarTab, 'numeric');
            app.FlapAngleEditField_2 = uieditfield(app.PerformancePolarTab, 'numeric');
            app.WakeStudyTab = uitab(app.TabGroup);
            app.WakeStudyTab.Title = 'Wake Study';
            app.TraverseTab = uitab(app.TabGroup);
            app.TraverseTab.Title = 'Traverse';
            app.GridSetupPanel = uipanel(app.TraverseTab);
            app.GridSetupPanel.Title = 'Grid Setup';
            app.YmaxEditFieldLabel = uilabel(app.GridSetupPanel);
            app.YmaxEditFieldLabel.Text = 'Ymax';
            app.YmaxEditField = uieditfield(app.GridSetupPanel, 'numeric');
            app.YStepLabel = uilabel(app.GridSetupPanel);
            app.YStepLabel.Text = 'Y Step';
            app.YStepEditField = uieditfield(app.GridSetupPanel, 'numeric');
            app.YStepEditField.Value = 1;
            app.YminEditFieldLabel = uilabel(app.GridSetupPanel);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.YminEditField = uieditfield(app.GridSetupPanel, 'numeric');
            app.ZminEditFieldLabel = uilabel(app.GridSetupPanel);
            app.ZminEditFieldLabel.Text = 'Zmin';
            app.ZminEditField = uieditfield(app.GridSetupPanel, 'numeric');
            app.ZmaxEditFieldLabel = uilabel(app.GridSetupPanel);
            app.ZmaxEditFieldLabel.Text = 'Zmax';
            app.ZmaxEditField = uieditfield(app.GridSetupPanel, 'numeric');
            app.ZStepEditFieldLabel = uilabel(app.GridSetupPanel);
            app.ZStepEditFieldLabel.Text = 'Z Step';
            app.ZStepEditField = uieditfield(app.GridSetupPanel, 'numeric');
            app.ZStepEditField.Value = 1;
            app.LockGridButton = uibutton(app.GridSetupPanel, 'state');
            app.LockGridButton.Text = 'Lock Grid';
            app.UpdateButton_2 = uibutton(app.GridSetupPanel, 'state');
            app.UpdateButton_2.Text = 'Update';
            app.StepLeftButton = uibutton(app.TraverseTab, 'push');
            app.StepLeftButton.ButtonPushedFcn = createCallbackFcn(app, @StepLeftButtonPushed, true);
            app.StepLeftButton.Text = 'Step Left';
            app.StepUpButton = uibutton(app.TraverseTab, 'push');
            app.StepUpButton.ButtonPushedFcn = createCallbackFcn(app, @StepUpButtonPushed, true);
            app.StepUpButton.Text = 'Step Up';
            app.StepDownButton = uibutton(app.TraverseTab, 'push');
            app.StepDownButton.ButtonPushedFcn = createCallbackFcn(app, @StepDownButtonPushed, true);
            app.StepDownButton.Text = 'Step Down';
            app.StepRightButton = uibutton(app.TraverseTab, 'push');
            app.StepRightButton.ButtonPushedFcn = createCallbackFcn(app, @StepRightButtonPushed, true);
            app.StepRightButton.Text = 'Step Right';
            app.StepSizeEditFieldLabel = uilabel(app.TraverseTab);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.TraverseTab, 'numeric');
            app.StepSizeEditField.Value = 1;
            app.SetOriginButton_2 = uibutton(app.TraverseTab, 'push');
            app.SetOriginButton_2.Text = 'Set Origin';
            app.GoHomeButton_2 = uibutton(app.TraverseTab, 'push');
            app.GoHomeButton_2.Text = 'Go Home';
            app.ToggleButtonGroup = uibuttongroup(app.TraverseTab);
            app.ToggleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ToggleButtonGroupSelectionChanged, true);
            app.ToggleButtonGroup.Title = 'Toggle';
            app.GoToButton = uitogglebutton(app.ToggleButtonGroup);
            app.GoToButton.Text = 'Go To';
            app.GoToButton.Value = true;
            app.GridSetupButton = uitogglebutton(app.ToggleButtonGroup);
            app.GridSetupButton.Text = 'Grid Setup';
            app.ManualControlButton = uitogglebutton(app.ToggleButtonGroup);
            app.ManualControlButton.Text = 'Manual Control';
            app.GoToPanel = uipanel(app.TraverseTab);
            app.GoToPanel.Title = 'Go To';
            app.YpositioninEditFieldLabel = uilabel(app.GoToPanel);
            app.YpositioninEditFieldLabel.Text = 'Y position (in)';
            app.yposmove = uieditfield(app.GoToPanel, 'numeric');
            app.ZpositioninEditFieldLabel = uilabel(app.GoToPanel);
            app.ZpositioninEditFieldLabel.Text = 'Z position (in)';
            app.zposmove = uieditfield(app.GoToPanel, 'numeric');
            app.SetOriginButton = uibutton(app.GoToPanel, 'push');
            app.SetOriginButton.Text = 'Set Origin';
            app.GoHomeButton = uibutton(app.GoToPanel, 'push');
            app.GoHomeButton.Text = 'Go Home';
            app.MoveButton = uibutton(app.GoToPanel, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.NegativeZButton = uibutton(app.TraverseTab, 'state');
            app.NegativeZButton.Text = 'Negative Z';
            app.NegativeYButton = uibutton(app.TraverseTab, 'state');
            app.NegativeYButton.Text = 'Negative Y';
            app.YpositionEditFieldLabel = uilabel(app.TraverseTab);
            app.YpositionEditFieldLabel.Text = 'Y-position';
            app.YpositionEditField = uieditfield(app.TraverseTab, 'numeric');
            app.ZpositionEditFieldLabel = uilabel(app.TraverseTab);
            app.ZpositionEditFieldLabel.Text = 'Z-position';
            app.ZpositionEditField = uieditfield(app.TraverseTab, 'numeric');
            app.CurrentPositionLabel = uilabel(app.TraverseTab);
            app.CurrentPositionLabel.Text = 'Current Position';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Wtapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end